classdef monitor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        ProportionalEditFieldLabel     matlab.ui.control.Label
        ProportionalEditField          matlab.ui.control.NumericEditField
        IntegralEditFieldLabel         matlab.ui.control.Label
        IntegralEditField              matlab.ui.control.NumericEditField
        DerivativeEditFieldLabel       matlab.ui.control.Label
        DerivativeEditField            matlab.ui.control.NumericEditField
        SetIButton                     matlab.ui.control.Button
        SetDButton                     matlab.ui.control.Button
        SetGoalButton                  matlab.ui.control.Button
        GoalTemperatureEditFieldLabel  matlab.ui.control.Label
        GoalTemperatureEditField       matlab.ui.control.NumericEditField
        ClearplotButton                matlab.ui.control.Button
        SetPButton                     matlab.ui.control.Button
        NewPEditField                  matlab.ui.control.NumericEditField
        NewIEditField                  matlab.ui.control.NumericEditField
        NewDEditField                  matlab.ui.control.NumericEditField
        NewGoalEditField               matlab.ui.control.NumericEditField
        CurrentLabel                   matlab.ui.control.Label
        NewLabel                       matlab.ui.control.Label
        CurrentTemperatureEditFieldLabel  matlab.ui.control.Label
        CurrentTemperatureEditField    matlab.ui.control.NumericEditField
        SensorInputEditFieldLabel      matlab.ui.control.Label
        SensorInputEditField           matlab.ui.control.NumericEditField
        HeaterOutputEditFieldLabel     matlab.ui.control.Label
        HeaterOutputEditField          matlab.ui.control.NumericEditField
        COMportsPanel                  matlab.ui.container.Panel
        COMportsDropDown               matlab.ui.control.DropDown
        RefreshButton                  matlab.ui.control.Button
        ConnectButton                  matlab.ui.control.StateButton
        SavealltoEEPROMButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        serialName = "";
        connected = false;
        serialObj;
        kp;
        ki;
        kd;
        curTemp;
        goalTemp;
        sensorInput;
        PWMOutput;
        plotTimer;
        plotCurTemp;
        plotGoalTemp;
        plotPWMOutput;
        plotKp;
        plotKi;
        plotKd;
        plotCurTempData;
        plotGoalTempData;
        plotPWMOutputData;
        plotKpData;
        plotKiData;
        plotKdData;
    end
    
    properties (Access = private, Constant = true)
        maxPlotTime = 5;
        plotInterval = 0.1;
    end
    
    methods (Access = private)
        
        function updateVarsCallback(app, src, evt)
            data = fscanf(app.serialObj);
            if ~isempty(data)
                switch data(1)
                    case 'T'
                        app.curTemp = str2double(data(2:end-1));
                        app.CurrentTemperatureEditField.Value = app.curTemp;
                    case 'G'
                        app.goalTemp = str2double(data(2:end-1));
                        app.GoalTemperatureEditField.Value = app.goalTemp;
                    case 'O'
                        app.PWMOutput = str2double(data(2:end-1));
                        app.HeaterOutputEditField.Value = app.PWMOutput;
                    case 'P'
                        app.kp = str2double(data(2:end-1));
                        app.ProportionalEditField.Value = app.kp;                       
                    case 'I'
                         app.ki = str2double(data(2:end-1));
                        app.IntegralEditField.Value = app.ki;
                    case 'D'
                        app.kd = str2double(data(2:end-1));
                        app.DerivativeEditField.Value = app.kd;
                    case 'S'
                        app.sensorInput = str2double(data(2:end-1));
                        app.SensorInputEditField.Value = app.sensorInput;
                    case 'E'
                        switch data(2:end-1)
                            case 'INVP'
                                fprintf('Invalid P value received by station');
                            case 'INVI'
                                fprintf('Invalid I value received by station');
                            case 'INVD'
                                fprintf('Invalid D value received by station');
                            case 'INVG'
                                fprintf('Invalid goal temperature value received by station');
                            case 'INVC'
                                fprintf('Invalid command received by station');
                            otherwise 
                                fprintf('Received message from station: %s', data(2:end)); 
                        end
                    otherwise
                        warning(['Invalid data received: ', data, '\n']);
                end
            end
        end
    
        function plotUpdate(app, src, evt)
            app.plotCurTempData = [app.plotCurTempData(2:end), app.curTemp];
            app.plotGoalTempData = [app.plotGoalTempData(2:end), app.goalTemp];
            app.plotPWMOutputData = [app.plotPWMOutputData(2:end), app.PWMOutput];
            app.plotKpData = [app.plotKpData(2:end), app.kp];
            app.plotKiData = [app.plotKiData(2:end), app.ki];
            app.plotKdData = [app.plotKdData(2:end), app.kd];
            yyaxis(app.UIAxes,'left');
            app.plotCurTemp.YData = app.plotCurTempData;
            app.plotGoalTemp.YData = app.plotGoalTempData;
            yyaxis(app.UIAxes,'right');
            app.plotPWMOutput.YData = app.plotPWMOutputData;
            app.plotKp.YData = app.plotKpData;
            app.plotKi.YData = app.plotKiData;
            app.plotKd.YData = app.plotKdData;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.COMportsDropDown.Items = seriallist();
            app.serialName = app.COMportsDropDown.Value;
            if isempty(app.COMportsDropDown.Value)
                app.ConnectButton.Enable = false;
            end
            app.SetPButton.Enable = false;
            app.SetIButton.Enable = false;
            app.SetDButton.Enable = false;
            app.SetGoalButton.Enable = false;
            
            app.kp = 0;
            app.ki = 0;
            app.kd = 0;
            app.curTemp = 0;
            app.goalTemp = 0;
            app.PWMOutput = 0;
            
            app.plotTimer = timer('ExecutionMode', 'fixedSpacing', 'Period', 0.5);
            app.plotTimer.TimerFcn = {@app.plotUpdate}; 
            
            app.plotCurTempData = zeros(1,app.maxPlotTime/app.plotInterval+1);
            app.plotGoalTempData = app.plotCurTempData;
            app.plotPWMOutputData = app.plotCurTempData;
            app.plotKpData = app.plotCurTempData;
            app.plotKiData = app.plotCurTempData;
            app.plotKdData = app.plotCurTempData;
            
            app.UIAxes.XLim = [0 app.maxPlotTime];
             
            yyaxis(app.UIAxes,'right')
            app.UIAxes.YLabel.String='PID parameters, (Output / 6)';
            app.UIAxes.YLim = [0 6];
            
            initialT = 0:0.1:app.maxPlotTime;
            app.plotPWMOutput = plot(app.UIAxes, initialT, app.plotPWMOutputData, 'Color', '#7E2F8E');
            hold(app.UIAxes, 'on');
            app.plotKp = plot(app.UIAxes, initialT, app.plotKpData, 'Color', '#A2142F');
            app.plotKi = plot(app.UIAxes, initialT, app.plotKiData, 'Color', '#77AC30');
            app.plotKd = plot(app.UIAxes, initialT, app.plotKdData, 'Color', '#4DBEEE');
            
            yyaxis(app.UIAxes,'left')
            app.UIAxes.YLabel.String='Temperature / \circC';
            app.UIAxes.YLim = [0 400];
            
            app.plotCurTemp = plot(app.UIAxes, initialT, app.plotCurTempData, 'Color', '#D95319');
            app.plotGoalTemp = plot(app.UIAxes, initialT, app.plotGoalTempData, 'Color', '#EDB120');
            hold(app.UIAxes, 'off');
            drawnow;
        end

        % Value changed function: COMportsDropDown
        function COMportsDropDownValueChanged(app, event)
            value = app.COMportsDropDown.Value;
            app.serialName = value;
            if value ~= ""
              app.ConnectButton.Enable = true;
            else
              app.ConnectButton.Enable = false;
            end
        end

        % Button pushed function: SetPButton
        function SetPButtonPushed(app, event)
            if (app.connected) && (app.NewPEditField.Value >= 0)
                fprintf(app.serialObj, 'P%.1f\n', app.NewPEditField.Value);
                disp('Updated P');
            end
        end

        % Button pushed function: SetIButton
        function SetIButtonPushed(app, event)
            if (app.connected) && (app.NewIEditField.Value >= 0)
                fprintf(app.serialObj, 'I%.1f\n', app.NewIEditField.Value);
                pause(1);
                disp('Updated I');
            end
        end

        % Button pushed function: SetDButton
        function SetDButtonPushed(app, event)
            if (app.connected) && (app.NewDEditField.Value >= 0)
                fprintf(app.serialObj, 'D%.1f\n', app.NewDEditField.Value);
                pause(1);
                disp('Updated D');
            end
        end

        % Button pushed function: SetGoalButton
        function SetGoalButtonPushed(app, event)
            if (app.connected) && (app.NewGoalEditField.Value >= 0)
                fprintf(app.serialObj, 'G%.1f\n', app.NewGoalEditField.Value);
                disp('Updated goal temperature');
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            fclose('all');
            delete(app.serialObj);
            delete(app.plotTimer);
            delete(app);
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.COMportsDropDown.Items = seriallist();
            if isempty(app.COMportsDropDown.Items)
                app.ConnectButton.Enable = false;
            else
                app.ConnectButton.Enable = true;
                app.COMportsDropDown.Value = app.COMportsDropDown.Items(1);
            end
        end

        % Value changed function: ConnectButton
        function ConnectButtonValueChanged(app, event)
            app.connected = app.ConnectButton.Value;
            if (app.connected)
                if isempty(app.serialObj)
                    app.serialObj = serial(app.serialName,'BaudRate',115200,'Terminator','LF');
                    app.serialObj.BytesAvailableFcn = {@app.updateVarsCallback};
                    app.serialObj.BytesAvailableFcnMode = 'terminator';
                end
                
                fopen(app.serialObj);
                pause(3);
                fprintf(app.serialObj,'U\n');
                % disp('Sent V');
                
                app.SetPButton.Enable = true;
                app.SetIButton.Enable = true;
                app.SetDButton.Enable = true;
                app.SetGoalButton.Enable = true;
                app.ConnectButton.Text = "Disconnect";
                app.connected = true;
                start(app.plotTimer);
            else
                app.SetPButton.Enable = false;
                app.SetIButton.Enable = false;
                app.SetDButton.Enable = false;
                app.SetGoalButton.Enable = false;
                app.ConnectButton.Text = "Connect";
                fclose(app.serialObj);
                app.connected = false;
                stop(app.plotTimer);
            end
        end

        % Button pushed function: ClearplotButton
        function ClearplotButtonPushed(app, event)
            app.plotCurTempData = zeros(1,app.maxPlotTime/app.plotInterval+1);
            app.plotGoalTempData = app.plotCurTempData;
            app.plotPWMOutputData = app.plotCurTempData;
            app.plotKpData = app.plotCurTempData;
            app.plotKiData = app.plotCurTempData;
            app.plotKdData = app.plotCurTempData;
            yyaxis(app.UIAxes,'left');
            app.plotCurTemp.YData = app.plotCurTempData;
            app.plotGoalTemp.YData = app.plotGoalTempData;
            yyaxis(app.UIAxes,'right');
            app.plotPWMOutput.YData = app.plotPWMOutputData;
            app.plotKp.YData = app.plotKpData;
            app.plotKi.YData = app.plotKiData;
            app.plotKd.YData = app.plotKdData;
            
            if (app.ConnectButton.Value)
                fprintf(app.serialObj,'U\n');
            end
        end

        % Button pushed function: SavealltoEEPROMButton
        function SavealltoEEPROMButtonPushed(app, event)
            if (app.connected) && (app.NewGoalEditField.Value >= 0)
                fprintf(app.serialObj, 'M\n');
                disp('Saved variables to EEPROM');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'Time / s')
            app.ProportionalEditFieldLabel = uilabel(app.UIFigure);
            app.ProportionalEditFieldLabel.Text = 'Proportional';
            app.ProportionalEditField = uieditfield(app.UIFigure, 'numeric');
            app.ProportionalEditField.Editable = 'off';
            app.IntegralEditFieldLabel = uilabel(app.UIFigure);
            app.IntegralEditFieldLabel.Text = 'Integral';
            app.IntegralEditField = uieditfield(app.UIFigure, 'numeric');
            app.IntegralEditField.Editable = 'off';
            app.DerivativeEditFieldLabel = uilabel(app.UIFigure);
            app.DerivativeEditFieldLabel.Text = 'Derivative';
            app.DerivativeEditField = uieditfield(app.UIFigure, 'numeric');
            app.DerivativeEditField.Editable = 'off';
            app.SetIButton = uibutton(app.UIFigure, 'push');
            app.SetIButton.ButtonPushedFcn = createCallbackFcn(app, @SetIButtonPushed, true);
            app.SetIButton.Text = 'Set I';
            app.SetDButton = uibutton(app.UIFigure, 'push');
            app.SetDButton.ButtonPushedFcn = createCallbackFcn(app, @SetDButtonPushed, true);
            app.SetDButton.Text = 'Set D';
            app.SetGoalButton = uibutton(app.UIFigure, 'push');
            app.SetGoalButton.ButtonPushedFcn = createCallbackFcn(app, @SetGoalButtonPushed, true);
            app.SetGoalButton.Text = 'Set Goal';
            app.GoalTemperatureEditFieldLabel = uilabel(app.UIFigure);
            app.GoalTemperatureEditFieldLabel.Text = 'Goal Temperature';
            app.GoalTemperatureEditField = uieditfield(app.UIFigure, 'numeric');
            app.GoalTemperatureEditField.Editable = 'off';
            app.ClearplotButton = uibutton(app.UIFigure, 'push');
            app.ClearplotButton.ButtonPushedFcn = createCallbackFcn(app, @ClearplotButtonPushed, true);
            app.ClearplotButton.Text = 'Clear plot';
            app.SetPButton = uibutton(app.UIFigure, 'push');
            app.SetPButton.ButtonPushedFcn = createCallbackFcn(app, @SetPButtonPushed, true);
            app.SetPButton.Text = 'Set P';
            app.NewPEditField = uieditfield(app.UIFigure, 'numeric');
            app.NewIEditField = uieditfield(app.UIFigure, 'numeric');
            app.NewDEditField = uieditfield(app.UIFigure, 'numeric');
            app.NewGoalEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurrentLabel = uilabel(app.UIFigure);
            app.CurrentLabel.Text = 'Current';
            app.NewLabel = uilabel(app.UIFigure);
            app.NewLabel.Text = 'New';
            app.CurrentTemperatureEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentTemperatureEditFieldLabel.Text = 'Current Temperature';
            app.CurrentTemperatureEditField = uieditfield(app.UIFigure, 'numeric');
            app.SensorInputEditFieldLabel = uilabel(app.UIFigure);
            app.SensorInputEditFieldLabel.Text = 'Sensor Input';
            app.SensorInputEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeaterOutputEditFieldLabel = uilabel(app.UIFigure);
            app.HeaterOutputEditFieldLabel.Text = 'Heater Output';
            app.HeaterOutputEditField = uieditfield(app.UIFigure, 'numeric');
            app.COMportsPanel = uipanel(app.UIFigure);
            app.COMportsPanel.Title = 'COM ports';
            app.COMportsDropDown = uidropdown(app.COMportsPanel);
            app.COMportsDropDown.Items = {'A'};
            app.COMportsDropDown.ValueChangedFcn = createCallbackFcn(app, @COMportsDropDownValueChanged, true);
            app.COMportsDropDown.Value = 'A';
            app.RefreshButton = uibutton(app.COMportsPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.ConnectButton = uibutton(app.COMportsPanel, 'state');
            app.ConnectButton.ValueChangedFcn = createCallbackFcn(app, @ConnectButtonValueChanged, true);
            app.ConnectButton.Text = 'Connect';
            app.SavealltoEEPROMButton = uibutton(app.UIFigure, 'push');
            app.SavealltoEEPROMButton.ButtonPushedFcn = createCallbackFcn(app, @SavealltoEEPROMButtonPushed, true);
            app.SavealltoEEPROMButton.Text = 'Save all to EEPROM';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = monitor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end